classdef cameraCalibInner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LONGHORNcameraAPPUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        Menu                       matlab.ui.container.Menu
        Menu_2                     matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        helpMenu                   matlab.ui.container.Menu
        aboutMenu                  matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        Button                     matlab.ui.control.Button
        imagesListListBox          matlab.ui.control.ListBox
        imagesListListBoxLabel     matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        Tab_2                      matlab.ui.container.Tab
        imagesListListBox_2        matlab.ui.control.ListBox
        imagesListListBox_2Label   matlab.ui.control.Label
        Button_2                   matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        allImageFullLists % 标定图像集合
        allValidImageFullLists % 验证图像集合

        % 相机标定参数
        detector
        imagePoints
        BoardSize % 单位：mm

        % 标靶验证参数
        fasterRCNNdetector
        imagePoints2x4
        BoardSize2
        camParams
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off')
            axis(app.UIAxes_2,'off')
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            % 打开标定图像集合
            [files,path] = uigetfile({'*.bmp'},...
                'Open calibration images','./','MultiSelect','on');
            if isequal(files,0)
                disp('User selected Cancel');
                return;
            end

            if numel(files)<2
                uialert(app.LONGHORNcameraAPPUIFigure,'标定至少需要2张以上棋盘图像','lack of images');
                return;
            end
            imageFileNames = string(fullfile(path,files));

            % 提示用户输入棋盘格点大小
            prompt = '请输入棋盘网格大小(mm):';
            dlgtitle = 'Input';
            boardSize = {'100'};
            answer = inputdlg(prompt,dlgtitle,1,boardSize);
            app.BoardSize = str2double(answer{1});


            % 检测棋盘格点并绘制显示
            % Detect calibration pattern in images
            app.detector = vision.calibration.monocular.CheckerboardDetector();
            d = uiprogressdlg(app.LONGHORNcameraAPPUIFigure,'Title','正在处理标定图像集...',...
                'Indeterminate','on');
            drawnow
            [app.imagePoints, imagesUsed] = detectPatternPoints(app.detector, imageFileNames);
            close(d)
            if size(app.imagePoints,3)<2
                uialert(app.LONGHORNcameraAPPUIFigure,'检测有效角点图像至少2张','lack of images');
                return;
            end
            app.allImageFullLists = imageFileNames(imagesUsed);

            % 显示
            [~,names,ext] = fileparts(app.allImageFullLists);
            app.imagesListListBox.Items = names+ext;
            app.imagesListListBox.Value = app.imagesListListBox.Items(1);% able selected show
            currSelectImage = imread(fullfile(path,app.imagesListListBox.Value));
            imshow(currSelectImage,Parent=app.UIAxes);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.imagePoints(:,1,1),app.imagePoints(:,2,1),'g-o',MarkerSize=10,LineWidth=1);
%             text(app.UIAxes,app.imagePoints(:,1,1)+20,app.imagePoints(:,2,1),string(1:size(app.imagePoints,1)),...
%                 Color="green");
            hold(app.UIAxes,"off");
            app.UIAxes.XLim = [0 width(currSelectImage)];
            app.UIAxes.YLim = [0 height(currSelectImage)];
        end

        % Clicked callback: imagesListListBox
        function imagesListListBoxClicked(app, event)
            selectIdx = event.InteractionInformation.Item;
            if isempty(selectIdx)
                return;
            end
            currSelectImage = imread(app.allImageFullLists(selectIdx));
            imshow(currSelectImage,Parent=app.UIAxes);

            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.imagePoints(:,1,selectIdx),app.imagePoints(:,2,selectIdx),'g-o',MarkerSize=10,LineWidth=1);
%             text(app.UIAxes,app.imagePoints(:,1,selectIdx)+40,app.imagePoints(:,2,selectIdx),string(1:size(app.imagePoints,1)),...
%                 Color="green");
            hold(app.UIAxes,"off");
            app.UIAxes.XLim = [0 width(currSelectImage)];
            app.UIAxes.YLim = [0 height(currSelectImage)];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 标定
            if size(app.imagePoints,3)<2
                return;
            end

            % Read the first image to obtain image size
            originalImage = imread(app.allImageFullLists(1));
            [mrows, ncols, ~] = size(originalImage);

            % Generate world coordinates for the planar pattern keypoints
            squareSize = app.BoardSize;  % in units of 'millimeters'
            worldPoints = generateWorldPoints(app.detector, 'SquareSize', squareSize);
            % Calibrate the camera
            [cameraParams, imagesUsed, estimationErrors] = estimateCameraParameters(app.imagePoints, worldPoints, ...
                'EstimateSkew', false, 'EstimateTangentialDistortion', true, ...
                'NumRadialDistortionCoefficients', 3, 'WorldUnits', 'millimeters', ...
                'InitialIntrinsicMatrix', [7353.8 0 0;0 7353.1 0;1922.4 1065.5 1], 'InitialRadialDistortion', [-0.16 -1.97 7.83], ...
                'ImageSize', [mrows, ncols]);

            d = uiprogressdlg(app.LONGHORNcameraAPPUIFigure,'Title','标定完成，正在输出报告...',...
                'Indeterminate','on');
            drawnow
            % View reprojection errors
            h1=figure(Name="repro errors"); showReprojectionErrors(cameraParams);

            % Visualize pattern locations
            h2=figure(Name="pattern loc"); showExtrinsics(cameraParams, 'CameraCentric');
      
            [imageFolder,~,~] = fileparts(app.allImageFullLists(1));
            figureErrFolder = fullfile(imageFolder,"误差报告图");
            undisImgFolder = fullfile(imageFolder,"去畸变图");
            if ~isfolder(figureErrFolder)
                mkdir(figureErrFolder)
            end
            if ~isfolder(undisImgFolder)
                mkdir(undisImgFolder)
            end

            exportgraphics(h1,fullfile(figureErrFolder,"重投影误差.jpg"),'Resolution',300);
            exportgraphics(h2,fullfile(figureErrFolder,"标定位置仿真.jpg"),'Resolution',300);

            % Display parameter estimation errors,输出到屏幕显示
            displayErrors(estimationErrors, cameraParams);

            % For example, you can use the calibration data to remove effects of lens distortion.
            for idx = 1:numel(app.allImageFullLists)
                originalImage = imread(app.allImageFullLists(idx));
                undistortedImage = undistortImage(originalImage, cameraParams);
                saveFilename = fullfile(undisImgFolder,string(idx)+".jpg");
                imwrite(undistortedImage,saveFilename);
            end

            % 计算每幅图像重投影误差
            errs = squeeze(mean(vecnorm(cameraParams.ReprojectionErrors,2,2),[1,2]));

            % cameraParams写到文本
            fid = fopen(fullfile(imageFolder,"cameraParams.txt"),"w");
            fprintf(fid,"内参矩阵：\r\n");
            fprintf(fid,"%.8f,%.8f,%.8f\r\n",cameraParams.K');
            fprintf(fid,"\r\n");

            fprintf(fid,"径向畸变系数:\r\n");
            fprintf(fid,"%.8f,%.8f,%.8f\r\n",cameraParams.RadialDistortion);

            fprintf(fid,"切向畸变系数:\r\n");
            fprintf(fid,"%.8f,%.8f\r\n",cameraParams.TangentialDistortion);
            fprintf(fid,"\r\n");

            fprintf(fid,"每幅图像的标定误差:\r\n");
            for iErr = 1:length(errs)
                fprintf(fid,"第%d幅图像的平均误差：%.8f像素\r\n",iErr,errs(iErr));
            end
            fprintf(fid,"总体平均每幅图像的平均误差：%.8f像素\r\n",mean(errs));
            fprintf(fid,"\r\n");

            fclose(fid);
            save(fullfile(imageFolder,"cameraParams.mat"),"cameraParams","estimationErrors");
            app.camParams = cameraParams;
            close(d);
        end

        % Clicked callback: imagesListListBox_2
        function imagesListListBox_2Clicked(app, event)
            % 验证列表选择
            selectIdx = event.InteractionInformation.Item;
            if isempty(selectIdx)
                return;
            end

            currSelectImage = imread(app.allValidImageFullLists(selectIdx));
            currSelectImage = undistortImage(currSelectImage,app.camParams);
            imshow(currSelectImage,Parent=app.UIAxes_2);

            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,app.imagePoints2x4(:,1,selectIdx),app.imagePoints2x4(:,2,selectIdx),'go',MarkerSize=10,LineWidth=1);
            text(app.UIAxes_2,app.imagePoints2x4(:,1,selectIdx)+40,app.imagePoints2x4(:,2,selectIdx),string(1:8),Color="green");
            hold(app.UIAxes_2,"off");
%             app.UIAxes_2.XLim = [0 width(currSelectImage)];
%             app.UIAxes_2.YLim = [0 height(currSelectImage)];
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            % 打开验证图片集
            if isempty(app.camParams)
                message = {'先标定！','请先“打开标定图像”进行标定！'};
                uialert(app.LONGHORNcameraAPPUIFigure,message,'Error',...
                    'Icon','error');
                return;
            end
            [files,path] = uigetfile({'*.bmp'},...
                'Open validation images','./','MultiSelect','on');
            if isequal(files,0)
                disp('User selected Cancel');
                return;
            end

            imageFileNames = string(fullfile(path,files));

            % 提示用户输入棋盘格点大小
            prompt = '请输入标靶棋盘网格大小(mm):';
            dlgtitle = 'Input';
            boardSize = {'355'};
            answer = inputdlg(prompt,dlgtitle,1,boardSize);
            app.BoardSize2 = str2double(answer{1});

            % 检测棋盘格点并绘制显示
            % Detect calibration pattern in images
            d0 = uiprogressdlg(app.LONGHORNcameraAPPUIFigure,'Title','load data',...
                'Indeterminate','on');
            drawnow
            addpath("buildInRefine");
            addpath("utils");
%             load data/ID14_cameraParams.mat cameraParams
            load data/fasterRcnnDetector.mat fasterRcnnDetector
            inputSize = [224,224,3];
            close(d0)


            % detect corners
            app.fasterRCNNdetector = fasterRcnnDetector;
            cameraParams = app.camParams;
            d = uiprogressdlg(app.LONGHORNcameraAPPUIFigure,'Title','正在处理标靶图片集...',...
                'Indeterminate','off');
            numValidImgs = numel(imageFileNames);
            app.imagePoints2x4 = [];
            app.allValidImageFullLists = [];
            for imgIdx = 1:numValidImgs
                d.Value = imgIdx/numValidImgs;
                d.Message = "process image "+string(imgIdx)+"/"+string(numValidImgs);

                img = imread(imageFileNames(imgIdx));
                img= undistortImage(img,cameraParams);
                imgFasterRcnn = im2single(imresize(img,inputSize(1:2)));
                [bboxes, scores, ~] = detect(fasterRcnnDetector,imgFasterRcnn);
                cropImg = img;
                offsetX = 0;
                offsetY = 0;
                if ~isempty(bboxes)
                    [~,idx] = maxk(scores,1);
                    bbox = bboxes(idx,:);
                    bbox = bboxresize(bbox,size(img,[1,2])./inputSize(1:2));
                    cropImg = imcrop(img,bbox);
                    offsetX = bbox(1);
                    offsetY = bbox(2);
                end
                imgPts = detectCheckerboardPointsMine(cropImg);
                if size(imgPts,1)==8
                    imgPts = [offsetX+imgPts(:,1)-1,offsetY+imgPts(:,2)-1];
                end

                %                 figure;imshow(img);hold on;
                if size(imgPts,1)~=8
                    [centers, radii, ~] = imfindcircles(img,[20 70],ObjectPolarity="dark",Sensitivity=0.5,EdgeThreshold=0.1);
                    %                     viscircles(centers, radii,'Color','b');
                    if size(centers,1)==2
                        centersDist = vecnorm(diff(centers));
                        offset = 0.25*centersDist;
                        center = mean(centers);
                        oriention = atan2(centers(2,2)-centers(1,2),centers(2,1)-centers(1,1));
                        bbox = [center,2*offset,centersDist+2*offset,oriention-pi/2];
                        [cropImg,tform] = imgCrop(img,bbox);
                        pts = detectCheckerboardPointsMine(cropImg);
                        if size(pts,1)==8
                            imgPts = transformPointsInverse(tform,pts);
                        end
                    end
                end

                if size(imgPts,1)==8
                    %% imagePoints排序,设计为与内建排序一致
                    orderedPts = sortCheckerboardPts(imgPts);% 8*2 size
                    app.imagePoints2x4 = cat(3,app.imagePoints2x4,orderedPts);
                    app.allValidImageFullLists = [app.allValidImageFullLists,imageFileNames(imgIdx)];
                end
            end
            close(d)

            % 显示
            [~,names,ext] = fileparts(app.allValidImageFullLists);
            app.imagesListListBox_2.Items = names+ext;
            app.imagesListListBox_2.Value = app.imagesListListBox_2.Items(1);% able selected show
            currSelectImage = imread(fullfile(path,app.imagesListListBox_2.Value));
            currSelectImage = undistortImage(currSelectImage,app.camParams);
            imshow(currSelectImage,Parent=app.UIAxes_2);
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,app.imagePoints2x4(:,1,1),app.imagePoints2x4(:,2,1),'go',MarkerSize=10,LineWidth=1);
            text(app.UIAxes_2,app.imagePoints2x4(:,1,1)+40,app.imagePoints2x4(:,2,1),string(1:8),Color="green");
            hold(app.UIAxes_2,"off");
            app.UIAxes_2.XLim = [0 width(currSelectImage)];
            app.UIAxes_2.YLim = [0 height(currSelectImage)];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 验证按钮
            if isempty(app.camParams)|| isempty(app.imagePoints2x4)
                return;
            end

            currentListValue = app.imagesListListBox_2.Value;
            [~,names,ext] = fileparts(app.allValidImageFullLists);
            selectImgId = matches(names+ext,currentListValue);
%             selectImgId = endsWith(app.allValidImageFullLists,currentListValue);
            selectImg = app.allValidImageFullLists(selectImgId);
            idx = find(selectImgId,1);
            orderedPts = app.imagePoints2x4(:,:,idx);

            %% 为第一个4个小棋盘格点生成GT,假定每个棋盘格宽度350mm
            worldPoints = generateCheckerboardPoints([3,3],app.BoardSize2);

            %% 计算2个标定模式的中心距看是否满足"2m"精度要求
            intrinsics = app.camParams.Intrinsics;
            camExtrinsics = estimateExtrinsics(orderedPts(1:4,:),worldPoints,intrinsics);
            % P = cameraProjection(intrinsics,camExtrinsics);
            worldPoints = img2world2d(orderedPts,camExtrinsics,intrinsics);
            cumDist = 0;
            pt1s = zeros(4,2);
            pt2s = zeros(4,2);
            for idx = 1:4
                pt1 = worldPoints(idx,:);
                pt2 = worldPoints(idx+4,:);
                cumDist = cumDist+vecnorm(pt1-pt2);
                pt1s(idx,:) = pt1;
                pt2s(idx,:) = pt2;
            end
            meanDist = cumDist/4;% 两个标靶圆心平均中心距
            ratio = (meanDist-2000)/2000;

            c1 = [mean(pt1s),0];
            c2 = [mean(pt2s),0];
            centerImgPts = world2img([c1;c2],camExtrinsics,intrinsics);

            hold(app.UIAxes_2,"on");
            drawline(app.UIAxes_2,Position=centerImgPts,Label=string(meanDist)+"mm");
            line0 = "SquareSize="+ string(app.BoardSize2)+"mm";
            line1 = "meatured Distance:"+string(meanDist)+"mm"+"/2000mm";
            line2 = "Ratio:"+string(ratio);
            line3 = "intrinsic K:";
            line4 = join(string(intrinsics.K(1,:)),",");
            line5 = join(string(intrinsics.K(2,:)),",");
            line6 = join(string(intrinsics.K(3,:)),",");
            line7 = "RadialDistortion(k1~k3)="+join(string(app.camParams.RadialDistortion),",");
            line8 = "TangentialDistortion(p1-p2)="+join(string(app.camParams.TangentialDistortion),",");
            
            showText = sprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s\n\n%s\n%s\n%s\n",...
                line0,line1,line2,line3,line4,line5,line6,line7,line8);
            text(app.UIAxes_2,1000,1000,showText,...
                fontsize=17,Color="red",FontWeight="bold");
            hold(app.UIAxes_2,"off");

            % output report,每张验证图对应一报告
            d = uiprogressdlg(app.LONGHORNcameraAPPUIFigure,'Title','正在为当前图像生成报告...',...
                'Indeterminate','on');
            drawnow
            [imageFolder,name,~] = fileparts(selectImg);
            figureValFolder = fullfile(imageFolder,"验证报告图");
            if ~isfolder(figureValFolder)
                mkdir(figureValFolder)
            end

            saveReportName = fullfile(figureValFolder,name+"_report.jpg");
            if isfile(saveReportName)
                delete(saveReportName);
            end
            exportgraphics(app.UIAxes_2,saveReportName);
            close(d);
        end

        % Menu selected function: helpMenu
        function helpMenuSelected(app, event)
            % help
            message = {'使用方法','1,选择"File->打开标定图像”','2,在"相机标定"标签页，选择标定按钮',...
                '3,选择"File->打开验证图像”','4,在"标靶验证"标签页，选择验证按钮',...
                '',...
                '步骤2执行后在原始标定图像文件夹会生成相机参数文件/夹',...
                '步骤4执行后在原始验证图像文件夹会生成报告'};
            uialert(app.LONGHORNcameraAPPUIFigure,message,'Help',...
                'Icon','info');
        end

        % Menu selected function: aboutMenu
        function aboutMenuSelected(app, event)
            % about
            message = {'关于','cuixingxing,2023.6.2','LONG-HORN'};
            uialert(app.LONGHORNcameraAPPUIFigure,message,'About',...
                'Icon','info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LONGHORNcameraAPPUIFigure = uifigure('Visible', 'off');
            app.LONGHORNcameraAPPUIFigure.Name = 'LONG-HORN cameraAPP';
            app.FileMenu = uimenu(app.LONGHORNcameraAPPUIFigure);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '打开标定图像';
            app.Menu_2 = uimenu(app.FileMenu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '打开验证图像';
            app.HelpMenu = uimenu(app.LONGHORNcameraAPPUIFigure);
            app.HelpMenu.Text = 'Help';
            app.helpMenu = uimenu(app.HelpMenu);
            app.helpMenu.MenuSelectedFcn = createCallbackFcn(app, @helpMenuSelected, true);
            app.helpMenu.Text = 'help';
            app.aboutMenu = uimenu(app.HelpMenu);
            app.aboutMenu.MenuSelectedFcn = createCallbackFcn(app, @aboutMenuSelected, true);
            app.aboutMenu.Text = 'about';
            app.TabGroup = uitabgroup(app.LONGHORNcameraAPPUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '相机标定';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, ' Detected Corners')
            app.imagesListListBoxLabel = uilabel(app.Tab);
            app.imagesListListBoxLabel.Text = 'images List';
            app.imagesListListBox = uilistbox(app.Tab);
            app.imagesListListBox.Items = {};
            app.imagesListListBox.ClickedFcn = createCallbackFcn(app, @imagesListListBoxClicked, true);
            app.imagesListListBox.Value = {};
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '标定';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '标靶验证';
            app.UIAxes_2 = uiaxes(app.Tab_2);
            title(app.UIAxes_2, 'Target Validation')
            app.Button_2 = uibutton(app.Tab_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '验证';
            app.imagesListListBox_2Label = uilabel(app.Tab_2);
            app.imagesListListBox_2Label.Text = 'images List';
            app.imagesListListBox_2 = uilistbox(app.Tab_2);
            app.imagesListListBox_2.Items = {};
            app.imagesListListBox_2.ClickedFcn = createCallbackFcn(app, @imagesListListBox_2Clicked, true);
            app.imagesListListBox_2.Value = {};
            % Show the figure after all components are created
            app.LONGHORNcameraAPPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cameraCalibInner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LONGHORNcameraAPPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LONGHORNcameraAPPUIFigure)
        end
    end
end